classdef App_Version1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        MechanismPlotterLabel         matlab.ui.control.Label
        GraphButton                   matlab.ui.control.Button
        ChoosetypeofmechanismListBoxLabel  matlab.ui.control.Label
        ChoosetypeofmechanismListBox  matlab.ui.control.ListBox
        LinkagedEditFieldLabel        matlab.ui.control.Label
        LinkagedEditField             matlab.ui.control.NumericEditField
        LinkagebEditFieldLabel        matlab.ui.control.Label
        LinkagebEditField             matlab.ui.control.NumericEditField
        LinkagecEditFieldLabel        matlab.ui.control.Label
        LinkagecEditField             matlab.ui.control.NumericEditField
        UIAxes                        matlab.ui.control.UIAxes
        InputAngleEditFieldLabel      matlab.ui.control.Label
        InputAngleEditField           matlab.ui.control.NumericEditField
        LinkageaEditFieldLabel        matlab.ui.control.Label
        LinkageaEditField             matlab.ui.control.NumericEditField
        ThemechanismcannotbeconstrutedLabel  matlab.ui.control.Label
        InclineEditFieldLabel         matlab.ui.control.Label
        InclineEditField              matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ChoosetypeofmechanismListBox
        function ChoosetypeofmechanismListBoxValueChanged(app, event)
          value = app.ChoosetypeofmechanismListBox.Value;
         
           switch value
               case 'Fourbar Linkage Mechanism'   
                app.InclineEditField.Visible='off';
                app.InclineEditFieldLabel.Visible='off';
                app.LinkageaEditField.Visible='on';
                app.LinkagebEditField.Visible='on';
                app.LinkagecEditField.Visible='on';
                app.LinkagedEditField.Visible='on';
                app.LinkageaEditFieldLabel.Visible='on';   
                app.LinkagebEditFieldLabel.Visible='on';
                app.LinkagecEditFieldLabel.Visible='on';
                app.LinkagedEditFieldLabel.Visible='on';
                case 'Fourbar Slider-Crank Mechanism'
                app.InclineEditField.Visible='off';
                app.InclineEditFieldLabel.Visible='off';
                app.LinkageaEditField.Visible='on';
                app.LinkagebEditField.Visible='on';
                app.LinkagecEditField.Visible='on';
                app.LinkagedEditField.Visible='off';
                app.LinkageaEditFieldLabel.Visible='on'; 
                app.LinkagebEditFieldLabel.Visible='on';
                app.LinkagecEditFieldLabel.Visible='on';
                app.LinkagedEditFieldLabel.Visible='off'; 
                case 'Inverted Slider-Crank Mechanism'
                app.InclineEditField.Visible='on';
                app.InclineEditFieldLabel.Visible='on';
                app.LinkageaEditField.Visible='on';
                app.LinkagebEditField.Visible='on';
                app.LinkagecEditField.Visible='on';
                app.LinkagedEditField.Visible='on';
                app.LinkageaEditFieldLabel.Visible='on';   
                app.LinkagebEditFieldLabel.Visible='on';
                app.LinkagecEditFieldLabel.Visible='on';
                app.LinkagedEditFieldLabel.Visible='on'; 
           end
        end

        % Button pushed function: GraphButton
        function GraphButtonPushed(app, event)
            
           value = app.ChoosetypeofmechanismListBox.Value;
         
           switch value
               case 'Fourbar Linkage Mechanism'
                  
                  
                   a=app.LinkageaEditField.Value;
                   b=app.LinkagebEditField.Value;
                   c=app.LinkagecEditField.Value;
                   d=app.LinkagedEditField.Value;
                   theta=deg2rad(app.InputAngleEditField.Value);
                   
                
                   k1=d/a;
                   k2=d/c;
                   k3=((a^2)-(b^2)+(c^2)+(d^2))/(2*a*c);
                   k4=d/b;
                   k5=((c^2)-(d^2)-(a^2)-(b^2))/(2*a*b);
                   A=cos(theta)-k1-(k2*cos(theta))+k3;
                   B=-2*sin(theta);
                   C=k1-((k2+1)*cos(theta))+k3;
                   D=cos(theta)-k1+(k4*cos(theta))+k5;
                   E=-2*sin(theta);
                   F=k1+((k4-1)*cos(theta))+k5;
                   dis=((B^2)-4*A*C);
                   dis2=((E^2)-4*D*F);
                      %poner if para caso imaginario
                      if dis>0
                          if dis2>0
                              app.ThemechanismcannotbeconstrutedLabel.Visible= 'off';
                   theta41=2*atan((-B+sqrt(dis))/2*A);
                   theta31=2*atan((-E+sqrt(dis2))/2*D);
                   
                   %graficar
                   x1=0; y1=0; x2=a*cos(theta); y2=a*sin(theta);
                   x4=d; y4=0;
                   x3=x4+(c*cos(theta41)); y3=y4+(c*sin(theta41));


                   plot(app.UIAxes, [x1 x2],[y1 y2], 'r', [x2 x3],[y2 y3], 'g', [x3 x4],[y3 y4], 'b')
                          else
                              app.ThemechanismcannotbeconstrutedLabel.Visible= 'on';
                               plot(app.UIAxes, [0 5],[0 5], 'r', [0 5],[5 0],'r' );
                          end
                      else
                            app.ThemechanismcannotbeconstrutedLabel.Visible= 'on';
                             plot(app.UIAxes, [0 5],[0 5], 'r', [0 5],[5 0],'r' );
                      end
                      
               case 'Fourbar Slider-Crank Mechanism'
                   %deshabilitar captura del dato d, cambiar nombre de eslabon 3 a descentre 
                   %generar imagenes guia sacadas de norton
                   a=app.LinkageaEditField.Value;
                   b=app.LinkagebEditField.Value;
                   c=app.LinkagecEditField.Value;
                   %d=app.LinkagedEditField.Value;
                   theta=deg2rad(app.InputAngleEditField.Value);
                   %theta31=asin(((a*sin(theta))-c)/b);
                   %cruazada
                   theta32=asin(-((a*sin(theta))-c)/b)+pi;
                   d=(a*cos(theta))-(b*cos(theta32));
                   
                   x1=0; y1=0; x3=d; y3=c;
                  x2=a*cos(theta); y2=a*sin(theta);
                   
                   plot(app.UIAxes, [x1 x2],[y1 y2], 'r', [x2 x3],[y2 y3], 'g')
                   
               case 'Inverted Slider-Crank Mechanism'
                   a=app.LinkageaEditField.Value;
                   b=app.LinkagebEditField.Value;
                   c=app.LinkagecEditField.Value;
                   d=app.LinkagedEditField.Value;
                   gamma=deg2rad(app.InclineEditField.Value);
                   theta=deg2rad(app.InputAngleEditField.Value);
                   
                   p=(a*sin(theta)*sin(gamma))+(((a*cos(theta))-d)*cos(gamma));
                   q=(-a*sin(theta)*cos(gamma))+(((a*cos(theta))-d)*sin(gamma)) ;
                   r=-c*sin(gamma);
                   s=r-q;
                   t=2*p;
                   u=q+r;
                   dis3=(T^2)-(4*s*u);
                   
                   if dis3>0
                   
                   theta41=2*atan((-t+sqrt(dis3))/(2*s));
                   theta3=theta4+gamma;
                   
                    %graficar
                   x1=0; y1=0; x2=a*cos(theta); y2=a*sin(theta);
                   x4=d; y4=0;
                   x3=x4+(c*cos(theta41)); y3=y4+(c*sin(theta41));


                   plot(app.UIAxes, [x1 x2],[y1 y2], 'r', [x2 x3],[y2 y3], 'g', [x3 x4],[y3 y4], 'b')
                   
                   else
                       
                        app.ThemechanismcannotbeconstrutedLabel.Visible= 'on';
                               plot(app.UIAxes, [0 5],[0 5], 'r', [0 5],[5 0],'r' );
                               
                   end
           end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.MechanismPlotterLabel = uilabel(app.UIFigure);
            app.MechanismPlotterLabel.Text = 'Mechanism Plotter';
            app.GraphButton = uibutton(app.UIFigure, 'push');
            app.GraphButton.ButtonPushedFcn = createCallbackFcn(app, @GraphButtonPushed, true);
            app.GraphButton.Text = 'Graph';
            app.ChoosetypeofmechanismListBoxLabel = uilabel(app.UIFigure);
            app.ChoosetypeofmechanismListBoxLabel.Text = 'Choose type of mechanism';
            app.ChoosetypeofmechanismListBox = uilistbox(app.UIFigure);
            app.ChoosetypeofmechanismListBox.Items = {'Fourbar Linkage Mechanism', 'Fourbar Slider-Crank Mechanism', 'Inverted Slider-Crank Mechanism'};
            app.ChoosetypeofmechanismListBox.ValueChangedFcn = createCallbackFcn(app, @ChoosetypeofmechanismListBoxValueChanged, true);
            app.ChoosetypeofmechanismListBox.Value = 'Fourbar Linkage Mechanism';
            app.LinkagedEditFieldLabel = uilabel(app.UIFigure);
            app.LinkagedEditFieldLabel.Text = 'Linkage d';
            app.LinkagedEditField = uieditfield(app.UIFigure, 'numeric');
            app.LinkagebEditFieldLabel = uilabel(app.UIFigure);
            app.LinkagebEditFieldLabel.Text = 'Linkage b';
            app.LinkagebEditField = uieditfield(app.UIFigure, 'numeric');
            app.LinkagecEditFieldLabel = uilabel(app.UIFigure);
            app.LinkagecEditFieldLabel.Text = 'Linkage c';
            app.LinkagecEditField = uieditfield(app.UIFigure, 'numeric');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Graph of mechanism')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.InputAngleEditFieldLabel = uilabel(app.UIFigure);
            app.InputAngleEditFieldLabel.Text = 'Input Angle';
            app.InputAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.LinkageaEditFieldLabel = uilabel(app.UIFigure);
            app.LinkageaEditFieldLabel.Text = 'Linkage a';
            app.LinkageaEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThemechanismcannotbeconstrutedLabel = uilabel(app.UIFigure);
            app.ThemechanismcannotbeconstrutedLabel.Text = 'The mechanism cannot be construted.';
            app.InclineEditFieldLabel = uilabel(app.UIFigure);
            app.InclineEditFieldLabel.Text = 'Incline';
            app.InclineEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_Version1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end