classdef FourbarLinkage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        HomeButton                 matlab.ui.control.Button
        GraphButton                matlab.ui.control.Button
        Title                      matlab.ui.control.Label
        Description                matlab.ui.control.Label
        ChooseanalysisButtonGroup  matlab.ui.container.ButtonGroup
        PositionsButton            matlab.ui.control.RadioButton
        VelocitiesButton           matlab.ui.control.RadioButton
        AccelerationsButton        matlab.ui.control.RadioButton
        DynamicForcesButton        matlab.ui.control.RadioButton
        InitialangularvelocityEditFieldLabel  matlab.ui.control.Label
        InitialangularvelocityEditField  matlab.ui.control.EditField
        InitialangularaccelerationEditFieldLabel  matlab.ui.control.Label
        InitialangularaccelerationEditField  matlab.ui.control.NumericEditField
        LengthsandAnglesPanel      matlab.ui.container.Panel
        LinkageaEditField          matlab.ui.control.NumericEditField
        LinkageaEditFieldLabel     matlab.ui.control.Label
        LinkagebEditFieldLabel     matlab.ui.control.Label
        LinkagebEditField          matlab.ui.control.NumericEditField
        LinkagecEditFieldLabel     matlab.ui.control.Label
        LinkagecEditField          matlab.ui.control.NumericEditField
        LinkagedEditFieldLabel     matlab.ui.control.Label
        LinkagedEditField          matlab.ui.control.NumericEditField
        InputAngleEditFieldLabel   matlab.ui.control.Label
        InputAngleEditField        matlab.ui.control.NumericEditField
        InclineEditFieldLabel      matlab.ui.control.Label
        InclineEditField           matlab.ui.control.NumericEditField
        ChoosehowthemechanismwillbeconstructedDropDownLabel  matlab.ui.control.Label
        PositionDataEntry          matlab.ui.control.DropDown
        CoordinatesPanel           matlab.ui.container.Panel
        x1EditFieldLabel           matlab.ui.control.Label
        x1EditField                matlab.ui.control.NumericEditField
        y1EditFieldLabel           matlab.ui.control.Label
        y1EditField                matlab.ui.control.NumericEditField
        x2EditFieldLabel           matlab.ui.control.Label
        x2EditField                matlab.ui.control.NumericEditField
        y2EditFieldLabel           matlab.ui.control.Label
        y2EditField                matlab.ui.control.NumericEditField
        x3EditFieldLabel           matlab.ui.control.Label
        x3EditField                matlab.ui.control.NumericEditField
        y3EditFieldLabel           matlab.ui.control.Label
        y3EditField                matlab.ui.control.NumericEditField
        x4EditFieldLabel           matlab.ui.control.Label
        x4EditField                matlab.ui.control.NumericEditField
        y4EditFieldLabel           matlab.ui.control.Label
        y4EditField                matlab.ui.control.NumericEditField
        FourbarImage               matlab.ui.control.Image
    end

    properties (Access = private)
        CallingApp; %This app is calling for main menu
        DialogApp; %Also is a app that call other app
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CoordinatesPanel.Visible = 'off';
            app.InitialangularvelocityEditField.Visible = 'off';
            app.InitialangularvelocityEditFieldLabel.Visible = 'off';
            app.InitialangularaccelerationEditField.Visible = 'off';
            app.InitialangularaccelerationEditFieldLabel.Visible = 'off';
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
%             %Delete the dialog box
%             delete(app)
            a = app.LinkageaEditField.Value; %Manivela
            b = app.LinkagebEditField.Value; %Acoplador
            c = app.LinkagecEditField.Value; %Salida
            d = app.LinkagedEditField.Value; %Piso
            T2 = app.InputAngleEditField.Value; %Ángulo de entrada
            T1 = app.InclineEditField.Value; %Inclinación
                    
            options = optimoptions('fsolve','Display','off');

            C = [a b c d T1 T2];
            
            fun = @(x) fFourBarLinkage(x,C);
            
            x0 = [50,90]; %Condiciones iniciales, T3, T4
            [x,fval,exitFlag] = fsolve(fun,x0,options);
            
            x = num2str(x);
            
            if exitFlag > 0
                exi = dialog('Position',[565 280 250 150],'Name',' ');
                uicontrol('Parent',exi,'Style','text','Position',[20 70 210 40],'String',strcat('The mechanism can be construted. The Grashof Condition is true. ',x));
                uicontrol('Parent',exi,'Position',[85 20 70 25],'String','Close','Callback','delete(gcf)');
            else
                err = dialog('Position',[565 280 250 150],'Name',' ');
                uicontrol('Parent',err,'Style','text','Position',[20 70 210 40],'String','The mechanism cannot be construted. The Grashof Condition is not true.');
                uicontrol('Parent',err,'Position',[85 20 70 25],'String','Close','Callback','delete(gcf)');
            end
            
        end

        % Button pushed function: GraphButton
        function GraphButtonPushed(app, event)
            value = app.ChooseanalysisButtonGroup.SelectedObject;
            value2 = app.PositionDataEntry.Value;
            switch value
                case app.PositionsButton
                    switch value2
                        case 'Lengths and Angles'
                            fun = @root2d; 
                            x0 = [50,90]; %Condiciones iniciales, T3, T4
                            options = optimoptions('fsolve','Display','off');
                            [x,fval,exitFlag] = fsolve(fun,x0,options);
%                             T3 = x(1);
                            T4 = x(2);
                            Inc = app.InclineEditField.Value;
                                        
                            if exitFlag > 0
                                %Values used to graph
                                x1 = 0; y1 = 0; x4 = d*cosd(Inc); y4 = d*sind(Inc);
                                x2 = a*cosd(T2+Inc); y2 = a*sind(T2+Inc);
                                xp = d+c*cosd(T4); yp = c*sind(T4);
                                x3 = xp*cosd(Inc)-yp*sind(Inc); y3= yp*cosd(Inc)+xp*sind(Inc);
                                
                                
                                %Open the graph of Position Analisys
                                app.DialogApp = PositionAnalisys(app, x1, y1, x2, y2, x3, y3, x4, y4, 1);
                            else
                                %The equation cannot be solved
                                err = dialog('Position',[565 280 250 150],'Name',' ');
                                uicontrol('Parent',err,'Style','text','Position',[20 70 210 40],'String','The mechanism cannot be construted. The Grashof Condition is not true.');
                                uicontrol('Parent',err,'Position',[85 20 70 25],'String','Close','Callback','delete(gcf)');
                            end
                        case 'Coordinates'
                            %Values used to graph
                            x1=app.x1EditField.Value; y1=app.y1EditField.Value; 
                            x2=app.x2EditField.Value; y2=app.y2EditField.Value;
                            x3=app.x3EditField.Value; y3=app.y3EditField.Value;
                            x4=app.x4EditField.Value; y4=app.y4EditField.Value;
                            
                            %Values to verify the Grashof Condition
                            a = sqrt(abs(x1-x2)^2+abs(y1-y2)^2);
                            b = sqrt(abs(x2-x3)^2+abs(y2-y3)^2);
                            c = sqrt(abs(x3-x4)^2+abs(y3-y4)^2);
                            d = sqrt(abs(x4-x1)^2+abs(y4-y1)^2);
                            
                            e = [a b c d];
                            S = min(e);
                            L = max(e);
                            
                            if S+L <= (a+b+c+d)-(S+L) %The Grashof Condition
                                %Open the graph of Position Analisys
                                app.DialogApp = PositionAnalisys(app, x1, y1, x2, y2, x3, y3, x4, y4, 1);
                            else
                                %The equation cannot be solved                
                                err = dialog('Position',[565 280 250 150],'Name',' ');
                                uicontrol('Parent',err,'Style','text','Position',[20 70 210 40],'String','The mechanism cannot be construted. The Grashof Condition is not true.');
                                uicontrol('Parent',err,'Position',[85 20 70 25],'String','Close','Callback','delete(gcf)');
                            end
                    end
                case app.VelocitiesButton
                case app.AccelerationsButton
                case app.DynamicForcesButton
            end
            
            function F = root2d(x)                  
                    %Values to graph mechanism
                    a = app.LinkageaEditField.Value; %Manivela
                    b = app.LinkagebEditField.Value; %Acoplador
                    c = app.LinkagecEditField.Value; %Salida
                    d = app.LinkagedEditField.Value; %Piso
                    T2 = app.InputAngleEditField.Value; %Ángulo de entrada
                    
                    F(1) = -a*cosd(T2)+c*cosd(x(2))+d-b*cosd(x(1)); 
                    F(2) = -a*sind(T2)+c*sind(x(2))-b*sind(x(1));
            end
            
        end

        % Selection changed function: ChooseanalysisButtonGroup
        function ChooseanalysisButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChooseanalysisButtonGroup.SelectedObject;
            switch selectedButton
                case app.PositionsButton                   
                    app.InitialangularvelocityEditField.Visible = 'off';
                    app.InitialangularvelocityEditFieldLabel.Visible = 'off';
                    app.InitialangularaccelerationEditField.Visible = 'off';
                    app.InitialangularaccelerationEditFieldLabel.Visible = 'off';
                case app.VelocitiesButton
                    app.InitialangularvelocityEditField.Visible = 'on';
                    app.InitialangularvelocityEditFieldLabel.Visible = 'on';
                    app.InitialangularaccelerationEditField.Visible = 'off';
                    app.InitialangularaccelerationEditFieldLabel.Visible = 'off';
                case app.AccelerationsButton
                    app.InitialangularvelocityEditField.Visible = 'on';
                    app.InitialangularvelocityEditFieldLabel.Visible = 'on';
                    app.InitialangularaccelerationEditField.Visible = 'on';
                    app.InitialangularaccelerationEditFieldLabel.Visible = 'on';                    
                case app.DynamicForcesButton
                    app.InitialangularvelocityEditField.Visible = 'on';
                    app.InitialangularvelocityEditFieldLabel.Visible = 'on';
                    app.InitialangularaccelerationEditField.Visible = 'on';
                    app.InitialangularaccelerationEditFieldLabel.Visible = 'on'; 
            end
        end

        % Value changed function: PositionDataEntry
        function PositionDataEntryValueChanged(app, event)
            value = app.PositionDataEntry.Value;
            switch value
                case 'Lengths and Angles'
                    app.LengthsandAnglesPanel.Visible = 'on';
                    app.CoordinatesPanel.Visible = 'off';
                case 'Coordinates'
                    app.LengthsandAnglesPanel.Visible = 'off';
                    app.CoordinatesPanel.Visible = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.HomeButton = uibutton(app.UIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.GraphButton = uibutton(app.UIFigure, 'push');
            app.GraphButton.ButtonPushedFcn = createCallbackFcn(app, @GraphButtonPushed, true);
            app.GraphButton.Text = 'Graph';
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = 'FOUR BAR LINKAGE';
            app.Description = uilabel(app.UIFigure);
            app.Description.Text = 'DESCRIPTION';
            app.ChooseanalysisButtonGroup = uibuttongroup(app.UIFigure);
            app.ChooseanalysisButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChooseanalysisButtonGroupSelectionChanged, true);
            app.ChooseanalysisButtonGroup.Title = 'Choose analysis';
            app.PositionsButton = uiradiobutton(app.ChooseanalysisButtonGroup);
            app.PositionsButton.Text = 'Positions';
            app.PositionsButton.Value = true;
            app.VelocitiesButton = uiradiobutton(app.ChooseanalysisButtonGroup);
            app.VelocitiesButton.Text = 'Velocities';
            app.AccelerationsButton = uiradiobutton(app.ChooseanalysisButtonGroup);
            app.AccelerationsButton.Text = 'Accelerations';
            app.DynamicForcesButton = uiradiobutton(app.ChooseanalysisButtonGroup);
            app.DynamicForcesButton.Text = 'Dynamic Forces';
            app.InitialangularvelocityEditFieldLabel = uilabel(app.UIFigure);
            app.InitialangularvelocityEditFieldLabel.Text = 'Initial angular velocity';
            app.InitialangularvelocityEditField = uieditfield(app.UIFigure, 'text');
            app.InitialangularvelocityEditField.Value = '0';
            app.InitialangularaccelerationEditFieldLabel = uilabel(app.UIFigure);
            app.InitialangularaccelerationEditFieldLabel.Text = 'Initial angular acceleration';
            app.InitialangularaccelerationEditField = uieditfield(app.UIFigure, 'numeric');
            app.LengthsandAnglesPanel = uipanel(app.UIFigure);
            app.LengthsandAnglesPanel.Title = 'Lengths and Angles';
            app.LinkageaEditField = uieditfield(app.LengthsandAnglesPanel, 'numeric');
            app.LinkageaEditField.Limits = [0 Inf];
            app.LinkageaEditField.Value = 1;
            app.LinkageaEditFieldLabel = uilabel(app.LengthsandAnglesPanel);
            app.LinkageaEditFieldLabel.Text = 'Linkage a';
            app.LinkagebEditFieldLabel = uilabel(app.LengthsandAnglesPanel);
            app.LinkagebEditFieldLabel.Text = 'Linkage b';
            app.LinkagebEditField = uieditfield(app.LengthsandAnglesPanel, 'numeric');
            app.LinkagebEditField.Limits = [0 Inf];
            app.LinkagebEditField.Value = 2.06;
            app.LinkagecEditFieldLabel = uilabel(app.LengthsandAnglesPanel);
            app.LinkagecEditFieldLabel.Text = 'Linkage c';
            app.LinkagecEditField = uieditfield(app.LengthsandAnglesPanel, 'numeric');
            app.LinkagecEditField.Limits = [0 Inf];
            app.LinkagecEditField.Value = 2.33;
            app.LinkagedEditFieldLabel = uilabel(app.LengthsandAnglesPanel);
            app.LinkagedEditFieldLabel.Text = 'Linkage d';
            app.LinkagedEditField = uieditfield(app.LengthsandAnglesPanel, 'numeric');
            app.LinkagedEditField.Limits = [0 Inf];
            app.LinkagedEditField.Value = 2.22;
            app.InputAngleEditFieldLabel = uilabel(app.LengthsandAnglesPanel);
            app.InputAngleEditFieldLabel.Text = 'Input Angle';
            app.InputAngleEditField = uieditfield(app.LengthsandAnglesPanel, 'numeric');
            app.InputAngleEditField.Limits = [-360 360];
            app.InputAngleEditField.Value = 45;
            app.InclineEditFieldLabel = uilabel(app.LengthsandAnglesPanel);
            app.InclineEditFieldLabel.Text = 'Incline';
            app.InclineEditField = uieditfield(app.LengthsandAnglesPanel, 'numeric');
            app.InclineEditField.Limits = [-360 360];
            app.ChoosehowthemechanismwillbeconstructedDropDownLabel = uilabel(app.UIFigure);
            app.ChoosehowthemechanismwillbeconstructedDropDownLabel.Text = 'Choose how the mechanism will be constructed:';
            app.PositionDataEntry = uidropdown(app.UIFigure);
            app.PositionDataEntry.Items = {'Lengths and Angles', 'Coordinates'};
            app.PositionDataEntry.ValueChangedFcn = createCallbackFcn(app, @PositionDataEntryValueChanged, true);
            app.PositionDataEntry.Value = 'Lengths and Angles';
            app.CoordinatesPanel = uipanel(app.UIFigure);
            app.CoordinatesPanel.Title = 'Coordinates';
            app.x1EditFieldLabel = uilabel(app.CoordinatesPanel);
            app.x1EditFieldLabel.Text = 'x1';
            app.x1EditField = uieditfield(app.CoordinatesPanel, 'numeric');
            app.x1EditField.Limits = [0 Inf];
            app.y1EditFieldLabel = uilabel(app.CoordinatesPanel);
            app.y1EditFieldLabel.Text = 'y1';
            app.y1EditField = uieditfield(app.CoordinatesPanel, 'numeric');
            app.y1EditField.Limits = [0 Inf];
            app.x2EditFieldLabel = uilabel(app.CoordinatesPanel);
            app.x2EditFieldLabel.Text = 'x2';
            app.x2EditField = uieditfield(app.CoordinatesPanel, 'numeric');
            app.x2EditField.Limits = [0 Inf];
            app.x2EditField.Value = 0.7071;
            app.y2EditFieldLabel = uilabel(app.CoordinatesPanel);
            app.y2EditFieldLabel.Text = 'y2';
            app.y2EditField = uieditfield(app.CoordinatesPanel, 'numeric');
            app.y2EditField.Limits = [0 Inf];
            app.y2EditField.Value = 0.7071;
            app.x3EditFieldLabel = uilabel(app.CoordinatesPanel);
            app.x3EditFieldLabel.Text = 'x3';
            app.x3EditField = uieditfield(app.CoordinatesPanel, 'numeric');
            app.x3EditField.Limits = [0 Inf];
            app.x3EditField.Value = 1.9903;
            app.y3EditFieldLabel = uilabel(app.CoordinatesPanel);
            app.y3EditFieldLabel.Text = 'y3';
            app.y3EditField = uieditfield(app.CoordinatesPanel, 'numeric');
            app.y3EditField.Limits = [0 Inf];
            app.y3EditField.Value = 2.3186;
            app.x4EditFieldLabel = uilabel(app.CoordinatesPanel);
            app.x4EditFieldLabel.Text = 'x4';
            app.x4EditField = uieditfield(app.CoordinatesPanel, 'numeric');
            app.x4EditField.Limits = [0 Inf];
            app.x4EditField.Value = 2.22;
            app.y4EditFieldLabel = uilabel(app.CoordinatesPanel);
            app.y4EditFieldLabel.Text = 'y4';
            app.y4EditField = uieditfield(app.CoordinatesPanel, 'numeric');
            app.y4EditField.Limits = [0 Inf];
            app.FourbarImage = uiimage(app.UIFigure);
            app.FourbarImage.ImageSource = '4BarLinkage.PNG';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FourbarLinkage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end