classdef Main_menu_V2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        StarButton                  matlab.ui.control.Button
        ChoosetypeofmechanismLabel  matlab.ui.control.Label
        Choosetypeofmechanism       matlab.ui.control.ListBox
        Title                       matlab.ui.control.Label
        AppDescription              matlab.ui.control.Label
        FourbarImage                matlab.ui.control.Image
        SliderCrankImage            matlab.ui.control.Image
        InvertedSliderImage         matlab.ui.control.Image
        FourbarDescription          matlab.ui.control.Label
        SliderCrankDescription      matlab.ui.control.Label
        InvertedSliderDescription   matlab.ui.control.Label
    end

    properties (Access = private)
        DialogApp; % Description
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SliderCrankImage.Visible = 'off';
            app.InvertedSliderImage.Visible = 'off';
            app.SliderCrankDescription.Visible = 'off';
            app.InvertedSliderDescription.Visible = 'off';
        end

        % Button pushed function: StarButton
        function Star(app, event)
            value = app.Choosetypeofmechanism.Value;
            switch value
                case 'Fourbar Linkage Mechanism'
                    app.DialogApp = FourbarLinkage;
                case 'Fourbar Slider-Crank Mechanism'
                    app.DialogApp = SliderCrankMechanism;
                case 'Inverted Slider-Crank Mechanism'
                    app.DialogApp = InvertedSliderCrankMechanism;
                    
            end
        end

        % Value changed function: Choosetypeofmechanism
        function ChoosetypeofmechanismValueChanged(app, event)
            value = app.Choosetypeofmechanism.Value;
            switch value
                case 'Fourbar Linkage Mechanism'
                    app.FourbarImage.Visible = 'on';
                    app.SliderCrankImage.Visible = 'off';
                    app.InvertedSliderImage.Visible = 'off';
                    app.FourbarDescription.Visible = 'on';
                    app.SliderCrankDescription.Visible = 'off';
                    app.InvertedSliderDescription.Visible = 'off';
                case 'Fourbar Slider-Crank Mechanism'
                    app.FourbarImage.Visible = 'off';
                    app.SliderCrankImage.Visible = 'on';
                    app.InvertedSliderImage.Visible = 'off';
                    app.FourbarDescription.Visible = 'off';
                    app.SliderCrankDescription.Visible = 'on';
                    app.InvertedSliderDescription.Visible = 'off';
                case 'Inverted Slider-Crank Mechanism'
                    app.FourbarImage.Visible = 'off';
                    app.SliderCrankImage.Visible = 'off';
                    app.InvertedSliderImage.Visible = 'on';
                    app.FourbarDescription.Visible = 'off';
                    app.SliderCrankDescription.Visible = 'off';
                    app.InvertedSliderDescription.Visible = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.StarButton = uibutton(app.UIFigure, 'push');
            app.StarButton.ButtonPushedFcn = createCallbackFcn(app, @Star, true);
            app.StarButton.Text = 'Star';
            app.ChoosetypeofmechanismLabel = uilabel(app.UIFigure);
            app.ChoosetypeofmechanismLabel.Text = 'Choose type of mechanism:';
            app.Choosetypeofmechanism = uilistbox(app.UIFigure);
            app.Choosetypeofmechanism.Items = {'Fourbar Linkage Mechanism', 'Fourbar Slider-Crank Mechanism', 'Inverted Slider-Crank Mechanism'};
            app.Choosetypeofmechanism.ValueChangedFcn = createCallbackFcn(app, @ChoosetypeofmechanismValueChanged, true);
            app.Choosetypeofmechanism.Value = 'Fourbar Linkage Mechanism';
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = 'ANALYSIS OF MECHANISM';
            app.AppDescription = uilabel(app.UIFigure);
            app.AppDescription.Text = {'Use this program as a graphing tool of a desired type of mechanism and additionally, '; 'obtain information about kinetics and kinematics of the system.'};
            app.FourbarImage = uiimage(app.UIFigure);
            app.FourbarImage.ImageSource = '4BarLinkage.PNG';
            app.SliderCrankImage = uiimage(app.UIFigure);
            app.SliderCrankImage.ImageSource = 'SliderCrank.PNG';
            app.InvertedSliderImage = uiimage(app.UIFigure);
            app.InvertedSliderImage.ImageSource = 'InvertedSlider.PNG';
            app.FourbarDescription = uilabel(app.UIFigure);
            app.FourbarDescription.Text = {'The fourbar linkage has been shown above to be the simplest possible pin-jointed mechanism'; 'for single degree of freedom controlled motion. It also appears in various disguises'; 'such as the slider-crank and the earn-follower. It is in fact the most common and'; 'ubiquitous device used in machinery. It is also extremely versatile in terms of the types'; 'of motion which it can generate.'; ''; 'The Grashof'; 'condition is a very simple relationship which predicts the rotation behavior or rotatability'; 'of a fourbar linkage''s inversions based only on the link lengths.'};
            app.SliderCrankDescription = uilabel(app.UIFigure);
            app.SliderCrankDescription.Text = 'Fourbar Slider-Crank is a mechanism...';
            app.InvertedSliderDescription = uilabel(app.UIFigure);
            app.InvertedSliderDescription.Text = 'Inverted Slider-Crank is a mechanism...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main_menu_V2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end