classdef PositionAnalisys < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        BackButton                    matlab.ui.control.Button
        POSITIONANALISYSLabel         matlab.ui.control.Label
        TypeofmechanismDropDownLabel  matlab.ui.control.Label
        TypeofmechanismDropDown       matlab.ui.control.DropDown
        InformationofMechanismPanel   matlab.ui.container.Panel
        LinkageaEditField             matlab.ui.control.NumericEditField
        LinkageaEditFieldLabel        matlab.ui.control.Label
        LinkagebEditFieldLabel        matlab.ui.control.Label
        LinkagebEditField             matlab.ui.control.NumericEditField
        LinkagecEditFieldLabel        matlab.ui.control.Label
        LinkagecEditField             matlab.ui.control.NumericEditField
        LinkagedEditFieldLabel        matlab.ui.control.Label
        LinkagedEditField             matlab.ui.control.NumericEditField
        InputAngleEditFieldLabel      matlab.ui.control.Label
        InputAngleEditField           matlab.ui.control.NumericEditField
        InclineEditFieldLabel         matlab.ui.control.Label
        InclineEditField              matlab.ui.control.NumericEditField
        TransmissionAngleEditField_2Label  matlab.ui.control.Label
        TransmissionAngleEditField    matlab.ui.control.NumericEditField
        SliderLabel                   matlab.ui.control.Label
        Slider                        matlab.ui.control.Slider
        ActionButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp; %This app is calling for FourbarLinkage.mlapp
        a = 0;
        b = 0;
        c = 0;
        d = 0;
        InAn = 0;
        IncAn = 0;
        theta3 = 0;
        theta4 = 0;
        Nvideo = true;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, x1, y1, x2, y2, x3, y3, x4, y4, Mech)
            %Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            
            %Graph mechanism
            plot(app.UIAxes, [x1 x2], [y1 y2], 'r', [x2 x3], [y2 y3], 'g', [x3 x4], [y3 y4], 'b')
            
            value = Mech;
            switch value
                case 1
                    %Variables
                    app.a = sqrt(abs(x1-x2)^2+abs(y1-y2)^2); %Manivela
                    app.b = sqrt(abs(x2-x3)^2+abs(y2-y3)^2); %Acoplador
                    app.c = sqrt(abs(x3-x4)^2+abs(y3-y4)^2); %Salida
                    app.d = sqrt(abs(x4-x1)^2+abs(y4-y1)^2); %Piso
                    app.InAn = rad2deg(atan(y2/x2)); %Ángulo de entrada
                    app.IncAn = rad2deg(atan(y4/x4)); %Ángulo del piso
                    
                    app.theta3 = rad2deg(atan((y3-y2)/(x3-x2)));
                    app.theta4 = rad2deg(atan((y3-y4)/(x3-x4)));
                    if (app.theta3 < 0)
                        app.theta3 = 180+app.theta3;
                    elseif (app.theta4 < 0)
                        app.theta4 = 180+app.theta4;
                    end
                    TrAn = app.theta3-app.theta4; %Ángulo de transmisión
                                        
                    %Asignar variables
                    app.LinkageaEditField.Value = app.a;
                    app.LinkagebEditField.Value = app.b;
                    app.LinkagecEditField.Value = app.c;
                    app.LinkagedEditField.Value = app.d;
                    app.InputAngleEditField.Value = app.InAn;
                    app.InclineEditField.Value = app.IncAn;
                    app.TransmissionAngleEditField.Value = TrAn;
                case 2
                case 3
            end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            %Delete the dialog box
            delete(app)
        end

        % Callback function: Slider, Slider
        function VideoMechanismButtonPushed(app, event)
            if app.Nvideo == false
                fun = @root2d; 
                x0 = [50,90]; %Condiciones iniciales, T3, T4
                options = optimoptions('fsolve','Display','off');
                x = fsolve(fun,x0,options);
%                T3 = x(1);
                T4 = x(2);
                Inc = app.InclineEditField.Value;
                
                x1 = 0; y1 = 0; x4 = app.d*cosd(Inc); y4 = app.d*sind(Inc);
                x2 = app.a*cosd(T2+Inc); y2 = app.a*sind(T2+Inc);
                xp = app.d+app.c*cosd(T4); yp = app.c*sind(T4);
                x3 = xp*cosd(Inc)-yp*sind(Inc); y3= yp*cosd(Inc)+xp*sind(Inc);
                
                plot(app.UIAxes, [x1 x2], [y1 y2], 'r', [x2 x3], [y2 y3], 'g', [x3 x4], [y3 y4], 'b')
            end
            
            
            function F = root2d(x)                  
                    %Values to graph mechanism
                    A = app.a; %Manivela
                    B = app.b; %Acoplador
                    C = app.c; %Salida
                    D = app.d; %Piso
                    T2 = app.Slider.Value; %Ángulo de entrada
                    
                    F(1) = -A*cosd(T2)+C*cosd(x(2))+D-B*cosd(x(1)); 
                    F(2) = -A*sind(T2)+C*sind(x(2))-B*sind(x(1));    
            end
 
% %Variables
%             a = app.LinkageaEditField.Value;
%             b = app.LinkagebEditField.Value;
%             c = app.LinkagecEditField.Value;
%             d = app.LinkagedEditField.Value;
%             Inc = app.InclineEditField.Value;
%             
%             v = VideoWriter('mecanismo1.avi'); %Creación de la animación
%             open(v);
%             
%             for T2=0:360
%                 
%                 %Variables
%                 k1=d/a;
%                 k2=d/c;
%                 k3=((a^2)-(b^2)+(c^2)+(d^2))/(2*a*c);
% %                 k4=d/b;
% %                 k5=((c^2)-(d^2)-(a^2)-(b^2))/(2*a*b);
%                 A=cosd(T2)-k1-(k2*cosd(T2))+k3;
%                 B=-2*sind(T2);
%                 C=k1-((k2+1)*cosd(T2))+k3;
% %                 D=cosd(T2)-k1+(k4*cosd(T2))+k5;
% %                 F=k1+((k4-1)*cosd(T2))+k5;
%                 
%                 %Ángulos solicitados
%                 T41=2*atan((-B+sqrt((B^2)-(4*A*C)))/(2*A)); %Ángulo para mecanismo cruzado
%                 T42=2*atan((-B-sqrt((B^2)-(4*A*C)))/(2*A)); %Ángulo para mecanismo abierto
%                 
% %                 T31=2*atan((-B+sqrt((B^2)-(4*D*F)))/(2*D)); %Ángulo para mecanismo cruzado
% %                 T32=2*atan((-B-sqrt((B^2)-(4*D*F)))/(2*D)); %Ángulo para mecanismo abierto
%                 
%                 T4 = rad2deg(T42);
%                 
%                 value = app.TypeofmechanismDropDown.Value;
%                 switch value
%                     case 'Open Circuit'
%                     T4 = rad2deg(T42); %Ángulo con el que se desea trabajar
%                     case 'Crossed Circuit'
%                     T4 = rad2deg(T41); %Ángulo con el que se desea trabajar
%                 end
%                 
%                 %Posiciones vectores
%                 x1 = 0; y1 = 0; x4 = d*cosd(Inc); y4 = d*sind(Inc);
%                 x2 = a*cosd(T2+Inc); y2 = a*sind(T2+Inc);
%                 xp = d+c*cosd(T4); yp = c*sind(T4);
%                 x3 = xp*cosd(Inc)-yp*sind(Inc); y3= yp*cosd(Inc)+xp*sind(Inc);
%                 
%                 %Video: Graph mechanism
%                 plot(app.UIAxes, [x1 x2], [y1 y2], 'r', [x2 x3], [y2 y3], 'g', [x3 x4], [y3 y4], 'b', [-2 3], [-2 3], 'white')
%                 
%                 frame = getframe;
%                 writeVideo(v,frame);
%                 clf('reset');
%             end
%             
%             close(v);
        end

        % Button pushed function: ActionButton
        function ActionButtonPushed(app, event)
            if app.Nvideo
                app.Nvideo = false;
            else
                app.Nvideo = true;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Graph of mechanism')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.POSITIONANALISYSLabel = uilabel(app.UIFigure);
            app.POSITIONANALISYSLabel.Text = 'POSITION ANALISYS';
            app.TypeofmechanismDropDownLabel = uilabel(app.UIFigure);
            app.TypeofmechanismDropDownLabel.Text = 'Type of mechanism';
            app.TypeofmechanismDropDown = uidropdown(app.UIFigure);
            app.TypeofmechanismDropDown.Items = {'Open Circuit', 'Crossed Circuit'};
            app.TypeofmechanismDropDown.Value = 'Open Circuit';
            app.InformationofMechanismPanel = uipanel(app.UIFigure);
            app.InformationofMechanismPanel.Title = 'Information of Mechanism';
            app.LinkageaEditField = uieditfield(app.InformationofMechanismPanel, 'numeric');
            app.LinkageaEditFieldLabel = uilabel(app.InformationofMechanismPanel);
            app.LinkageaEditFieldLabel.Text = 'Linkage a';
            app.LinkagebEditFieldLabel = uilabel(app.InformationofMechanismPanel);
            app.LinkagebEditFieldLabel.Text = 'Linkage b';
            app.LinkagebEditField = uieditfield(app.InformationofMechanismPanel, 'numeric');
            app.LinkagecEditFieldLabel = uilabel(app.InformationofMechanismPanel);
            app.LinkagecEditFieldLabel.Text = 'Linkage c';
            app.LinkagecEditField = uieditfield(app.InformationofMechanismPanel, 'numeric');
            app.LinkagedEditFieldLabel = uilabel(app.InformationofMechanismPanel);
            app.LinkagedEditFieldLabel.Text = 'Linkage d';
            app.LinkagedEditField = uieditfield(app.InformationofMechanismPanel, 'numeric');
            app.InputAngleEditFieldLabel = uilabel(app.InformationofMechanismPanel);
            app.InputAngleEditFieldLabel.Text = 'Input Angle';
            app.InputAngleEditField = uieditfield(app.InformationofMechanismPanel, 'numeric');
            app.InclineEditFieldLabel = uilabel(app.InformationofMechanismPanel);
            app.InclineEditFieldLabel.Text = 'Incline';
            app.InclineEditField = uieditfield(app.InformationofMechanismPanel, 'numeric');
            app.TransmissionAngleEditField_2Label = uilabel(app.InformationofMechanismPanel);
            app.TransmissionAngleEditField_2Label.Text = 'Transmission Angle';
            app.TransmissionAngleEditField = uieditfield(app.InformationofMechanismPanel, 'numeric');
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 360];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @VideoMechanismButtonPushed, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @VideoMechanismButtonPushed, true);
            app.ActionButton = uibutton(app.UIFigure, 'push');
            app.ActionButton.ButtonPushedFcn = createCallbackFcn(app, @ActionButtonPushed, true);
            app.ActionButton.Text = 'Action';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PositionAnalisys(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end